classdef transmit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        WordEditField  matlab.ui.control.EditField
        InputButton    matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: InputButton
        function WordEditFieldValueChanged(app, event)
            word = app.WordEditField.Value;
       
              pilotV=['11111111';'11111111';'11111111';'11111111';'11111111'];
              rxDecVec=dec2bin(word);
              
            for k=1:length(rxDecVec(:,1))
                if length(rxDecVec(k,:))<8
                    rxDecV(k,:)=horzcat('0',rxDecVec(k,:));
                end
            end
            
            rxDecV=vertcat(pilotV,rxDecV,pilotV);
            
            for k=1:length(rxDecV(:,1))
                for kk=1:4
                   x_1to4(k,kk)=str2double(rxDecV(k,kk));
                   x_5to8(k,kk)=str2double(rxDecV(k,kk+4));
                end       
                
            end
            x_1to4=x_1to4'; x_5to8=x_5to8';
            
            Parity=[1 1 1 0;0 1 1 1;1 1 0 1];
            G=vertcat(eye(4),Parity);
            
            c1=mod(G*x_1to4,2); c2=mod(G*x_5to8,2);


              bx1_points=[61,61,100,100];
              by1_points=[0,480,480,0];
              bx2_points=[161,161,200,200];
              by2_points=[0,480,480,0];
              bx3_points=[261,261,300,300];
              by3_points=[0,480,480,0];
              bx4_points=[361,361,400,400];
              by4_points=[0,480,480,0];
              bx5_points=[461,461,500,500];
              by5_points=[0,480,480,0];
              bx6_points=[561,561,600,600];
              by6_points=[0,480,480,0];

              
              for k=1:length(c1(1,:))
              tic;
              if c1(1,k)==1
              rx1_1_points=[0,0,60,60];
              ry1_1_points=[0,220,220,0];
              else
              rx1_1_points=0;
              ry1_1_points=0;    
              end
                
              if c1(2,k)==1
              rx2_1_points=[101,101,160,160];
              ry2_1_points=[0,220,220,0];
              else
              rx2_1_points=0;
              ry2_1_points=0;
              end
                
              if c1(3,k)==1
              rx3_1_points=[201,201,260,260];
              ry3_1_points=[0,220,220,0];
              else
              rx3_1_points=0;
              ry3_1_points=0;
              end
              
              if c1(4,k)==1
              rx4_1_points=[301,301,360,360];
              ry4_1_points=[0,220,220,0];
              else
              rx4_1_points=0;
              ry4_1_points=0;
              end
              
              if c1(5,k)==1
              rx5_1_points=[401,401,460,460];
              ry5_1_points=[0,220,220,0];
              else
              rx5_1_points=0;
              ry5_1_points=0;
              end
              
              if c1(6,k)==1
              rx6_1_points=[501,501,560,560];
              ry6_1_points=[0,220,220,0];
              else
              rx6_1_points=0;
              ry6_1_points=0;    
              end
              
              if c1(7,k)==1
              rx7_1_points=[601,601,660,660];
              ry7_1_points=[0,220,220,0];
              else
              rx7_1_points=0;
              ry7_1_points=0;    
              end
              
              if c2(1,k)==1
              rx1_2_points=[0,0,60,60];
              ry1_2_points=[260,480,480,260];
              else
              rx1_2_points=0;
              ry1_2_points=0;    
              end
                
              if c2(2,k)==1
              rx2_2_points=[101,101,160,160];
              ry2_2_points=[260,480,480,260];
              else
              rx2_2_points=0;
              ry2_2_points=0;
              end
                
              if c2(3,k)==1
              rx3_2_points=[201,201,260,260];
              ry3_2_points=[260,480,480,260];
              else
              rx3_2_points=0;
              ry3_2_points=0;
              end
              
              if c2(4,k)==1
              rx4_2_points=[301,301,360,360];
              ry4_2_points=[260,480,480,260];
              else
              rx4_2_points=0;
              ry4_2_points=0;
              end
              
              if c2(5,k)==1
              rx5_2_points=[401,401,460,460];
              ry5_2_points=[260,480,480,260];
              else
              rx5_2_points=0;
              ry5_2_points=0;
              end
              
              if c2(6,k)==1
              rx6_2_points=[501,501,560,560];
              ry6_2_points=[260,480,480,260];
              else
              rx6_2_points=0;
              ry6_2_points=0;    
              end
              
              if c2(7,k)==1
              rx7_2_points=[601,601,660,660];
              ry7_2_points=[260,480,480,260];
              else
              rx7_2_points=0;
              ry7_2_points=0;    
              end
             
              
              fill(app.UIAxes,...
                  rx1_1_points,ry1_1_points,[1 0 0],rx2_1_points,ry2_1_points,[1 0 0],rx3_1_points,ry3_1_points,[1 0 0],rx4_1_points,ry4_1_points,[1 0 0],...
                  rx5_1_points,ry5_1_points,[1 0 0],rx6_1_points,ry6_1_points,[1 0 0],rx7_1_points,ry7_1_points,[1 0 0],...
                  rx1_2_points,ry1_2_points,[1 0 0],rx2_2_points,ry2_2_points,[1 0 0],rx3_2_points,ry3_2_points,[1 0 0],rx4_2_points,ry4_2_points,[1 0 0],...
                  rx5_2_points,ry5_2_points,[1 0 0],rx6_2_points,ry6_2_points,[1 0 0],rx7_2_points,ry7_2_points,[1 0 0],...
                  bx1_points,by1_points,[0 0 1],bx2_points,by2_points,[0 0 1],bx3_points,by3_points,[0 0 1],bx4_points,by4_points,[0 0 1],...
                  bx5_points,by5_points,[0 0 1],bx6_points,by6_points,[0 0 1]); 
              
              timeElapsed=toc;
              pause(0.3-timeElapsed);
              end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 660];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.WordEditField = uieditfield(app.UIFigure, 'text');
            app.InputButton = uibutton(app.UIFigure, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @WordEditFieldValueChanged, true);
            app.InputButton.Text = 'Input';
        end
    end

    methods (Access = public)

        % Construct app
        function app = transmit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end